classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        pHLamp                         matlab.ui.control.Lamp
        pHLampLabel                    matlab.ui.control.Label
        MoistureLamp                   matlab.ui.control.Lamp
        MoistureLampLabel              matlab.ui.control.Label
        HumidityLampLabel              matlab.ui.control.Label
        HumidityLamp                   matlab.ui.control.Lamp
        TemperatureLamp                matlab.ui.control.Lamp
        TemperatureLampLabel           matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        pHEditField_2                  matlab.ui.control.NumericEditField
        pHEditField_2Label             matlab.ui.control.Label
        HumidityEditField_2            matlab.ui.control.NumericEditField
        HumidityEditField_2Label       matlab.ui.control.Label
        MoistureEditField_2            matlab.ui.control.NumericEditField
        MoistureEditField_2Label       matlab.ui.control.Label
        TemperaratureEditField_2       matlab.ui.control.NumericEditField
        TemperaratureEditField_2Label  matlab.ui.control.Label
        StandardvalueLabel_2           matlab.ui.control.Label
        pHGauge                        matlab.ui.control.Gauge
        pHGaugeLabel                   matlab.ui.control.Label
        MoistureGauge                  matlab.ui.control.Gauge
        MoistureGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.Gauge
        HumidityGaugeLabel             matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.Gauge
        TemperatureGaugeLabel          matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        GraphLabel                     matlab.ui.control.Label
        ValueFromSensorsLabel          matlab.ui.control.Label
        pHEditField                    matlab.ui.control.NumericEditField
        pHEditFieldLabel               matlab.ui.control.Label
        GUIForKrishiBotLabel           matlab.ui.control.Label
        MoistureEditField              matlab.ui.control.NumericEditField
        MoistureEditFieldLabel         matlab.ui.control.Label
        HumidityEditField              matlab.ui.control.NumericEditField
        HumidityEditFieldLabel         matlab.ui.control.Label
        TemperaratureEditField         matlab.ui.control.NumericEditField
        TemperaratureEditFieldLabel    matlab.ui.control.Label
        SwitchingPannelLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        stop;
        done=0;
        a;
        farmbots;

    end
    
    methods (Access = private)
        
       
        function startsystem(app)
        app.Switch.Value = 'On';
        app.farmbots = 1;
        readData(app);
    end

    function stopsystem(app)
        app.Switch.Value = 'Off';
        app.farmbots = 0;
        fwrite('0');
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.a = arduino('COM5','Uno','Libraries','SPI','BaudRate','115200');


        end

        % Value changed function: TemperaratureEditField
        function TemperaratureEditFieldValueChanged(app, event)

            temperature = app.TemperaratureEditField.Value;
            app.TemperatureGauge.Value = app.TemperaratureEditField.Value;

           
            if temperature > 0 && temperature <= 500
               app.TemperatureLamp.Color = 'g ';
            else
                app.TemperatureLamp.Color = 'red';
            end

            
        end

        % Value changed function: MoistureEditField
        function MoistureEditFieldValueChanged(app, event)
           moisture = app.MoistureEditField.Value;
            app.MoistureGauge.Value = app.MoistureEditField.Value;
           
            if moisture > 0 && moisture <= 500
               app.MoistureLamp.Color = 'g ';
            else
                app.MoistureLamp.Color = 'red';
            end
        end

        % Value changed function: HumidityEditField
        function HumidityEditFieldValueChanged(app, event)
          humidity = app.HumidityEditField.Value;
          
            app.HumidityGauge.Value = app.HumidityEditField.Value;
           
            if humidity > 0 && humidity <= 500
               app.HumidityLamp.Color = 'g ';
            else
                app.HumidityLamp.Color = 'red';
            end
        end

        % Value changed function: pHEditField
        function pHEditFieldValueChanged(app, event)
           
            pH = app.pHEditField.Value;
             app.pHGauge.Value = app.pHEditField.Value;

            if pH > 0 && pH <= 500
               app.pHLamp.Color = 'g ';
            else
                app.pHLamp.Color = 'red';
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
value = app.Switch.Value;

             if (strcmpi(value,'On'))
                startSystem(app);
            else
                stopSystem(app);
            end
            
            if strcmp (value,'On')
                app.Lamp.Color = 'g';
            else 
                app.Lamp.Color = 'r';
            end
          
        end

        % Callback function
        function StartButtonPushed(app, event)
          
        end

        % Value changed function: TemperaratureEditField_2
        function TemperaratureEditField_2ValueChanged(app, event)
            value = app.TemperaratureEditField_2.Value;
            app.TemperaratureEditField_2.Value = "28";
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1301, 1301};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SwitchLabel = uilabel(app.LeftPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.LeftPanel);
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = 'Status';
            app.TemperatureLampLabel = uilabel(app.LeftPanel);
            app.TemperatureLampLabel.Text = 'Temperature';
            app.TemperatureLamp = uilamp(app.LeftPanel);
            app.HumidityLamp = uilamp(app.LeftPanel);
            app.HumidityLampLabel = uilabel(app.LeftPanel);
            app.HumidityLampLabel.Text = 'Humidity';
            app.MoistureLampLabel = uilabel(app.LeftPanel);
            app.MoistureLampLabel.Text = 'Moisture';
            app.MoistureLamp = uilamp(app.LeftPanel);
            app.pHLampLabel = uilabel(app.LeftPanel);
            app.pHLampLabel.Text = 'pH';
            app.pHLamp = uilamp(app.LeftPanel);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.RightPanel = uipanel(app.GridLayout);
            app.TemperaratureEditFieldLabel = uilabel(app.RightPanel);
            app.TemperaratureEditFieldLabel.Text = 'Temperarature';
            app.TemperaratureEditField = uieditfield(app.RightPanel, 'numeric');
            app.TemperaratureEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaratureEditFieldValueChanged, true);
            app.HumidityEditFieldLabel = uilabel(app.RightPanel);
            app.HumidityEditFieldLabel.Text = 'Humidity';
            app.HumidityEditField = uieditfield(app.RightPanel, 'numeric');
            app.HumidityEditField.ValueChangedFcn = createCallbackFcn(app, @HumidityEditFieldValueChanged, true);
            app.MoistureEditFieldLabel = uilabel(app.RightPanel);
            app.MoistureEditFieldLabel.Text = 'Moisture';
            app.MoistureEditField = uieditfield(app.RightPanel, 'numeric');
            app.MoistureEditField.ValueChangedFcn = createCallbackFcn(app, @MoistureEditFieldValueChanged, true);
            app.GUIForKrishiBotLabel = uilabel(app.RightPanel);
            app.GUIForKrishiBotLabel.Text = 'GUI For KrishiBot';
            app.pHEditFieldLabel = uilabel(app.RightPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.RightPanel, 'numeric');
            app.pHEditField.ValueChangedFcn = createCallbackFcn(app, @pHEditFieldValueChanged, true);
            app.ValueFromSensorsLabel = uilabel(app.RightPanel);
            app.ValueFromSensorsLabel.Text = ' Value From Sensors';
            app.GraphLabel = uilabel(app.RightPanel);
            app.GraphLabel.Text = 'Graph';
            app.Label = uilabel(app.RightPanel);
            app.TemperatureGaugeLabel = uilabel(app.RightPanel);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.RightPanel, 'circular');
            app.HumidityGaugeLabel = uilabel(app.RightPanel);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.HumidityGauge = uigauge(app.RightPanel, 'circular');
            app.MoistureGaugeLabel = uilabel(app.RightPanel);
            app.MoistureGaugeLabel.Text = 'Moisture';
            app.MoistureGauge = uigauge(app.RightPanel, 'circular');
            app.pHGaugeLabel = uilabel(app.RightPanel);
            app.pHGaugeLabel.Text = 'pH';
            app.pHGauge = uigauge(app.RightPanel, 'circular');
            app.StandardvalueLabel_2 = uilabel(app.RightPanel);
            app.StandardvalueLabel_2.Text = 'Standard value';
            app.TemperaratureEditField_2Label = uilabel(app.RightPanel);
            app.TemperaratureEditField_2Label.Text = 'Temperarature';
            app.TemperaratureEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.TemperaratureEditField_2.ValueChangedFcn = createCallbackFcn(app, @TemperaratureEditField_2ValueChanged, true);
            app.MoistureEditField_2Label = uilabel(app.RightPanel);
            app.MoistureEditField_2Label.Text = 'Moisture';
            app.MoistureEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.HumidityEditField_2Label = uilabel(app.RightPanel);
            app.HumidityEditField_2Label.Text = 'Humidity';
            app.HumidityEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.pHEditField_2Label = uilabel(app.RightPanel);
            app.pHEditField_2Label.Text = 'pH';
            app.pHEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.SwitchingPannelLabel = uilabel(app.UIFigure);
            app.SwitchingPannelLabel.Text = 'Switching Pannel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end